classdef DataDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CurrentTab                    matlab.ui.container.Tab
        VDataPanel                    matlab.ui.container.Panel
        VMeanValue                    matlab.ui.control.NumericEditField
        MeanvalueEditField_4Label     matlab.ui.control.Label
        VMinimumValue                 matlab.ui.control.NumericEditField
        MinimumvalueEditField_4Label  matlab.ui.control.Label
        VMaximumValue                 matlab.ui.control.NumericEditField
        MaximumvalueEditField_4Label  matlab.ui.control.Label
        UDataPanel                    matlab.ui.container.Panel
        UMeanValue                    matlab.ui.control.NumericEditField
        MeanvalueEditField_3Label     matlab.ui.control.Label
        UMinimumValue                 matlab.ui.control.NumericEditField
        MinimumvalueEditField_3Label  matlab.ui.control.Label
        UMaximumValue                 matlab.ui.control.NumericEditField
        MaximumvalueEditField_3Label  matlab.ui.control.Label
        CurrentAxes                   matlab.ui.control.UIAxes
        TemperatureTab                matlab.ui.container.Tab
        TemperatureDataPanel          matlab.ui.container.Panel
        TemperatureMeanValue          matlab.ui.control.NumericEditField
        MeanvalueEditField_2Label     matlab.ui.control.Label
        TemperatureMinimumValue       matlab.ui.control.NumericEditField
        MinimumvalueEditField_2Label  matlab.ui.control.Label
        TemperatureMaximumValue       matlab.ui.control.NumericEditField
        MaximumvalueEditField_2Label  matlab.ui.control.Label
        TemperatureAxes               matlab.ui.control.UIAxes
        SalinityTab                   matlab.ui.container.Tab
        SalinityDataPanel             matlab.ui.container.Panel
        SalinityMeanValue             matlab.ui.control.NumericEditField
        MeanvalueEditFieldLabel       matlab.ui.control.Label
        SalinityMinimumValue          matlab.ui.control.NumericEditField
        MinimumvalueEditFieldLabel    matlab.ui.control.Label
        SalinityMaximumValue          matlab.ui.control.NumericEditField
        MaximumvalueEditFieldLabel    matlab.ui.control.Label
        SalinityAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Description
        Color % Description
        IsArea % Description
    end

    methods (Access = public)

        function updateData(app, data)
            switch app.Color
                case 1
                    hold(app.CurrentAxes);
                    try %#ok<*TRYNC>
                        histogram(app.CurrentAxes, data.u(~isnan(data.u)));

                        h = histogram(app.CurrentAxes, data.v(~isnan(data.v)));
                        hold(app.CurrentAxes, 'off');

                        edges = h.BinEdges;

                        hcu = histcounts(data.u(~isnan(data.u)), edges);
                        hcv = histcounts(data.v(~isnan(data.v)), edges);

                        b = bar(app.CurrentAxes, edges(1:end-1), [hcu; hcv]);
                        legend(app.CurrentAxes, 'u', 'v');
                        b(1).FaceColor = [1 0 0];
                        b(2).FaceColor = [1 .8 .8];
                    end

                    title(app.CurrentAxes, "Current Data Histogram");
                    xlabel(app.CurrentAxes, "Value");
                    ylabel(app.CurrentAxes, "Quantity");

                    try histogram(app.SalinityAxes, data.s(~isnan(data.s)), 'FaceColor', 'r'), end
                    title(app.SalinityAxes, "Salinity Data Histogram");
                    xlabel(app.SalinityAxes, "Value");
                    ylabel(app.SalinityAxes, "Quantity");

                    try histogram(app.TemperatureAxes, data.thetao(~isnan(data.thetao)), 'FaceColor', 'r'), end
                    title(app.TemperatureAxes, "Temperature Data Histogram");
                    xlabel(app.TemperatureAxes, "Value");
                    ylabel(app.TemperatureAxes, "Quantity");

                case 2
                    hold(app.CurrentAxes);
                    try %#ok<*TRYNC>
                        histogram(app.CurrentAxes, data.u(~isnan(data.u)));

                        h = histogram(app.CurrentAxes, data.v(~isnan(data.v)));
                        hold(app.CurrentAxes, 'off');

                        edges = h.BinEdges;

                        hcu = histcounts(data.u(~isnan(data.u)), edges);
                        hcv = histcounts(data.v(~isnan(data.v)), edges);

                        b = bar(app.CurrentAxes, edges(1:end-1), [hcu; hcv]);
                        legend(app.CurrentAxes, 'u', 'v');
                        b(1).FaceColor = [0 1 0];
                        b(2).FaceColor = [.8 1 .8];
                    end

                    title(app.CurrentAxes, "Current Data Histogram");
                    xlabel(app.CurrentAxes, "Value");
                    ylabel(app.CurrentAxes, "Quantity");

                    try histogram(app.SalinityAxes, data.s(~isnan(data.s)), 'FaceColor', 'g'), end
                    title(app.SalinityAxes, "Salinity Data Histogram");
                    xlabel(app.SalinityAxes, "Value");
                    ylabel(app.SalinityAxes, "Quantity");

                    try histogram(app.TemperatureAxes, data.thetao(~isnan(data.thetao)), 'FaceColor', 'g'), end
                    title(app.TemperatureAxes, "Temperature Data Histogram");
                    xlabel(app.TemperatureAxes, "Value");
                    ylabel(app.TemperatureAxes, "Quantity");

                case 3
                    hold(app.CurrentAxes);
                    try %#ok<*TRYNC>
                        histogram(app.CurrentAxes, data.u(~isnan(data.u)));

                        h = histogram(app.CurrentAxes, data.v(~isnan(data.v)));
                        hold(app.CurrentAxes, 'off');

                        edges = h.BinEdges;

                        hcu = histcounts(data.u(~isnan(data.u)), edges);
                        hcv = histcounts(data.v(~isnan(data.v)), edges);

                        b = bar(app.CurrentAxes, edges(1:end-1), [hcu; hcv]);
                        legend(app.CurrentAxes, 'u', 'v');
                        b(1).FaceColor = [0 0 1];
                        b(2).FaceColor = [.8 .8 1];
                    end

                    title(app.CurrentAxes, "Current Data Histogram");
                    xlabel(app.CurrentAxes, "Value");
                    ylabel(app.CurrentAxes, "Quantity");

                    try histogram(app.SalinityAxes, data.s(~isnan(data.s)), 'FaceColor', 'b'), end
                    title(app.SalinityAxes, "Salinity Data Histogram");
                    xlabel(app.SalinityAxes, "Value");
                    ylabel(app.SalinityAxes, "Quantity");

                    try histogram(app.TemperatureAxes, data.thetao(~isnan(data.thetao)), 'FaceColor', 'b'), end
                    title(app.TemperatureAxes, "Temperature Data Histogram");
                    xlabel(app.TemperatureAxes, "Value");
                    ylabel(app.TemperatureAxes, "Quantity");

                case 4
                    hold(app.CurrentAxes);
                    try %#ok<*TRYNC>
                        histogram(app.CurrentAxes, data.u(~isnan(data.u)));

                        h = histogram(app.CurrentAxes, data.v(~isnan(data.v)));
                        hold(app.CurrentAxes, 'off');

                        edges = h.BinEdges;

                        hcu = histcounts(data.u(~isnan(data.u)), edges);
                        hcv = histcounts(data.v(~isnan(data.v)), edges);

                        b = bar(app.CurrentAxes, edges(1:end-1), [hcu; hcv]);
                        legend(app.CurrentAxes, 'u', 'v');
                        b(1).FaceColor = [1 1 0];
                        b(2).FaceColor = [1 1 .8];
                    end

                    title(app.CurrentAxes, "Current Data Histogram");
                    xlabel(app.CurrentAxes, "Value");
                    ylabel(app.CurrentAxes, "Quantity");

                    try histogram(app.SalinityAxes, data.s(~isnan(data.s)), 'FaceColor', 'y'), end
                    title(app.SalinityAxes, "Salinity Data Histogram");
                    xlabel(app.SalinityAxes, "Value");
                    ylabel(app.SalinityAxes, "Quantity");

                    try histogram(app.TemperatureAxes, data.thetao(~isnan(data.thetao)), 'FaceColor', 'y'), end
                    title(app.TemperatureAxes, "Temperature Data Histogram");
                    xlabel(app.TemperatureAxes, "Value");
                    ylabel(app.TemperatureAxes, "Quantity");

                case 5
                    hold(app.CurrentAxes);
                    try %#ok<*TRYNC>
                        histogram(app.CurrentAxes, data.u(~isnan(data.u)));

                        h = histogram(app.CurrentAxes, data.v(~isnan(data.v)));
                        hold(app.CurrentAxes, 'off');

                        edges = h.BinEdges;

                        hcu = histcounts(data.u(~isnan(data.u)), edges);
                        hcv = histcounts(data.v(~isnan(data.v)), edges);

                        b = bar(app.CurrentAxes, edges(1:end-1), [hcu; hcv]);
                        legend(app.CurrentAxes, 'u', 'v');
                        b(1).FaceColor = [0 0 0];
                        b(2).FaceColor = [.8 .8 .8];
                    end

                    title(app.CurrentAxes, "Current Data Histogram");
                    xlabel(app.CurrentAxes, "Value");
                    ylabel(app.CurrentAxes, "Quantity");

                    try histogram(app.SalinityAxes, data.s(~isnan(data.s)), 'FaceColor', 'k'), end
                    title(app.SalinityAxes, "Salinity Data Histogram");
                    xlabel(app.SalinityAxes, "Value");
                    ylabel(app.SalinityAxes, "Quantity");

                    try histogram(app.TemperatureAxes, data.thetao(~isnan(data.thetao)), 'FaceColor', 'k'), end
                    title(app.TemperatureAxes, "Temperature Data Histogram");
                    xlabel(app.TemperatureAxes, "Value");
                    ylabel(app.TemperatureAxes, "Quantity");
            end

            try
                app.UMaximumValue.Value = max(max(data.u));
                app.UMinimumValue.Value = min(min(data.u));
                app.UMeanValue.Value = mean(mean(data.u, 'omitnan'), 'omitnan');

                app.VMaximumValue.Value = max(max(data.v));
                app.VMinimumValue.Value = min(min(data.v));
                app.VMeanValue.Value = mean(mean(data.v, 'omitnan'), 'omitnan');
            end

            try
                app.TemperatureMaximumValue.Value = max(max(data.thetao));
                app.TemperatureMinimumValue.Value = min(min(data.thetao));
                app.TemperatureMeanValue.Value = mean(mean(data.thetao, 'omitnan'), 'omitnan');
            end

            try
                app.SalinityMaximumValue.Value = max(max(data.s));
                app.SalinityMinimumValue.Value = min(min(data.s));
                app.SalinityMeanValue.Value = mean(mean(data.s, 'omitnan'), 'omitnan');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, flags, data, color, isArea)
            app.CallingApp = main;
            app.Color = color;
            app.IsArea = isArea;

            %             app.MaxLabel.Text = num2str(max(max(h)));
            %             app.MinLabel.Text = num2str(min(min(h)));
            %             app.MeanLabel.Text = num2str(mean(mean(h, 'omitnan'), 'omitnan'));

            if not(flags.uo) || not(flags.vo)
                app.CurrentTab.Parent = [];
            end

            if not(flags.so)
                app.SalinityTab.Parent = [];
            end

            if not(flags.thetao)
                app.TemperatureTab.Parent = [];
            end

            switch color
                case 1
                    if app.IsArea
                        app.UIFigure.Name = "Red Area Data";
                    else
                        app.UIFigure.Name = "Red Trail Data";
                    end
                case 2
                    if app.IsArea
                        app.UIFigure.Name = "Green Area Data";
                    else
                        app.UIFigure.Name = "Green Trail Data";
                    end
                case 3
                    if app.IsArea
                        app.UIFigure.Name = "Blue Area Data";
                    else
                        app.UIFigure.Name = "Blue Trail Data";
                    end
                case 4
                    if app.IsArea
                        app.UIFigure.Name = "Yellow Area Data";
                    else
                        app.UIFigure.Name = "Yellow Trail Data";
                    end
                case 5
                    if app.IsArea
                        app.UIFigure.Name = "Black Area Data";
                    else
                        app.UIFigure.Name = "Black Trail Data";
                    end
            end

            updateData(app, data);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsArea
                resetOpenAreaButton(app.CallingApp, app.Color);
            else
                resetOpenTrailButton(app.CallingApp, app.Color);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CurrentTab = uitab(app.TabGroup);
            app.CurrentTab.Title = 'Current';
            app.CurrentAxes = uiaxes(app.CurrentTab);
            title(app.CurrentAxes, 'Title')
            xlabel(app.CurrentAxes, 'X')
            ylabel(app.CurrentAxes, 'Y')
            zlabel(app.CurrentAxes, 'Z')
            app.UDataPanel = uipanel(app.CurrentTab);
            app.UDataPanel.Title = 'U Data';
            app.MaximumvalueEditField_3Label = uilabel(app.UDataPanel);
            app.MaximumvalueEditField_3Label.Text = 'Maximum value';
            app.UMaximumValue = uieditfield(app.UDataPanel, 'numeric');
            app.UMaximumValue.Editable = 'off';
            app.MinimumvalueEditField_3Label = uilabel(app.UDataPanel);
            app.MinimumvalueEditField_3Label.Text = 'Minimum value';
            app.UMinimumValue = uieditfield(app.UDataPanel, 'numeric');
            app.UMinimumValue.Editable = 'off';
            app.MeanvalueEditField_3Label = uilabel(app.UDataPanel);
            app.MeanvalueEditField_3Label.Text = 'Mean value';
            app.UMeanValue = uieditfield(app.UDataPanel, 'numeric');
            app.UMeanValue.Editable = 'off';
            app.VDataPanel = uipanel(app.CurrentTab);
            app.VDataPanel.Title = 'V Data';
            app.MaximumvalueEditField_4Label = uilabel(app.VDataPanel);
            app.MaximumvalueEditField_4Label.Text = 'Maximum value';
            app.VMaximumValue = uieditfield(app.VDataPanel, 'numeric');
            app.VMaximumValue.Editable = 'off';
            app.MinimumvalueEditField_4Label = uilabel(app.VDataPanel);
            app.MinimumvalueEditField_4Label.Text = 'Minimum value';
            app.VMinimumValue = uieditfield(app.VDataPanel, 'numeric');
            app.VMinimumValue.Editable = 'off';
            app.MeanvalueEditField_4Label = uilabel(app.VDataPanel);
            app.MeanvalueEditField_4Label.Text = 'Mean value';
            app.VMeanValue = uieditfield(app.VDataPanel, 'numeric');
            app.VMeanValue.Editable = 'off';
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.TemperatureAxes = uiaxes(app.TemperatureTab);
            title(app.TemperatureAxes, 'Title')
            xlabel(app.TemperatureAxes, 'X')
            ylabel(app.TemperatureAxes, 'Y')
            zlabel(app.TemperatureAxes, 'Z')
            app.TemperatureDataPanel = uipanel(app.TemperatureTab);
            app.TemperatureDataPanel.Title = 'Temperature Data';
            app.MaximumvalueEditField_2Label = uilabel(app.TemperatureDataPanel);
            app.MaximumvalueEditField_2Label.Text = 'Maximum value';
            app.TemperatureMaximumValue = uieditfield(app.TemperatureDataPanel, 'numeric');
            app.TemperatureMaximumValue.Editable = 'off';
            app.MinimumvalueEditField_2Label = uilabel(app.TemperatureDataPanel);
            app.MinimumvalueEditField_2Label.Text = 'Minimum value';
            app.TemperatureMinimumValue = uieditfield(app.TemperatureDataPanel, 'numeric');
            app.TemperatureMinimumValue.Editable = 'off';
            app.MeanvalueEditField_2Label = uilabel(app.TemperatureDataPanel);
            app.MeanvalueEditField_2Label.Text = 'Mean value';
            app.TemperatureMeanValue = uieditfield(app.TemperatureDataPanel, 'numeric');
            app.TemperatureMeanValue.Editable = 'off';
            app.SalinityTab = uitab(app.TabGroup);
            app.SalinityTab.Title = 'Salinity';
            app.SalinityAxes = uiaxes(app.SalinityTab);
            title(app.SalinityAxes, 'Title')
            xlabel(app.SalinityAxes, 'X')
            ylabel(app.SalinityAxes, 'Y')
            zlabel(app.SalinityAxes, 'Z')
            app.SalinityDataPanel = uipanel(app.SalinityTab);
            app.SalinityDataPanel.Title = 'Salinity Data';
            app.MaximumvalueEditFieldLabel = uilabel(app.SalinityDataPanel);
            app.MaximumvalueEditFieldLabel.Text = 'Maximum value';
            app.SalinityMaximumValue = uieditfield(app.SalinityDataPanel, 'numeric');
            app.SalinityMaximumValue.Editable = 'off';
            app.MinimumvalueEditFieldLabel = uilabel(app.SalinityDataPanel);
            app.MinimumvalueEditFieldLabel.Text = 'Minimum value';
            app.SalinityMinimumValue = uieditfield(app.SalinityDataPanel, 'numeric');
            app.SalinityMinimumValue.Editable = 'off';
            app.MeanvalueEditFieldLabel = uilabel(app.SalinityDataPanel);
            app.MeanvalueEditFieldLabel.Text = 'Mean value';
            app.SalinityMeanValue = uieditfield(app.SalinityDataPanel, 'numeric');
            app.SalinityMeanValue.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end