classdef MapSelectorDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapSelectorUIFigure  matlab.ui.Figure
        CancelButton         matlab.ui.control.Button
        MapSelectorPanel     matlab.ui.container.Panel
        MapSelectListBox     matlab.ui.control.ListBox
        SelectmapButton      matlab.ui.control.Button
        DateSelectorPanel    matlab.ui.container.Panel
        DateSelectListBox    matlab.ui.control.ListBox
        ModelSelectorPanel   matlab.ui.container.Panel
        ModelSelectListBox   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        CallingApp % Main app
        MapPath % Map folder path
        Start % Description
    end
    
    methods (Access = private)
        
        function folderNames = getFolderNames(~, auxPath)
            files = dir(auxPath);
            flags = [files.isdir];
            folders = files(flags);
            folderNames = cell(1, numel(folders) - 2);
            for i=3:numel(folders)
                folderNames{i-2} = folders(i).name;
            end
        end
        
        function filenames = getNcFileNames(~, auxPath)
            files = dir(fullfile(auxPath, '*.nc'));
            flags = [files.isdir];
            ncfiles = files(not(flags));
            filenames = cell(1, numel(ncfiles));
            for i=1:numel(ncfiles)
                filenames{i} = ncfiles(i).name;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, mapPath, start)
            app.CallingApp = main;
            app.MapPath = mapPath;
            app.Start = start;
            
            if start
                app.CancelButton.Enable = 'off';
            end
            
            modelFolders = app.getFolderNames(mapPath);
            app.ModelSelectListBox.Items = ["..." modelFolders];
            
            app.DateSelectorPanel.Enable = 'off';
            app.MapSelectorPanel.Enable = 'off';
            
            app.SelectmapButton.Enable = 'off';
        end

        % Value changed function: ModelSelectListBox
        function ModelSelectListBoxValueChanged(app, event)
            datePath = strcat(app.MapPath, '\', app.ModelSelectListBox.Value);
            
            dateFolders = app.getFolderNames(datePath);
            
            if isempty(dateFolders)
                app.DateSelectListBox.Items = ["..." "Nothing found"];
                app.DateSelectorPanel.Enable = 'off';
            else
                app.DateSelectListBox.Items = ["..." dateFolders];
                app.DateSelectorPanel.Enable = 'on';
            end
            
            app.MapSelectListBox.Items = ["..." "Nothing found"];
            app.MapSelectorPanel.Enable = 'off';
            
            app.SelectmapButton.Enable = 'off';
        end

        % Value changed function: DateSelectListBox
        function DateSelectListBoxValueChanged(app, event)
            mapPath = strcat(app.MapPath, '\', ...
                             app.ModelSelectListBox.Value, '\', ...
                             app.DateSelectListBox.Value);
            
            mapFiles = app.getNcFileNames(mapPath);
            
            if isempty(mapFiles)
                app.MapSelectListBox.Items = ["..." "Nothing found"];
                app.MapSelectorPanel.Enable = 'off';
            else
                app.MapSelectListBox.Items = ["..." mapFiles];
                app.MapSelectorPanel.Enable = 'on';
            end
            
            app.SelectmapButton.Enable = 'off';
        end

        % Value changed function: MapSelectListBox
        function MapSelectListBoxValueChanged(app, event)
            if app.ModelSelectListBox.Value == "NLOM" && length(app.MapSelectListBox.Value) == 2 && app.MapSelectListBox.Value(1) ~= "..." && app.MapSelectListBox.Value(2) ~= "..."
                app.SelectmapButton.Enable = 'on';
            elseif app.ModelSelectListBox.Value == "IBI" && length(app.MapSelectListBox.Value) == 1 && app.MapSelectListBox.Value ~= "..."
                app.SelectmapButton.Enable = 'on';
            else
                app.SelectmapButton.Enable = 'off';
            end            
        end

        % Button pushed function: SelectmapButton
        function SelectmapButtonPushed(app, event)
            try %#ok<*TRYNC> 
                app.CallingApp.LoadMapMenu.Enable = 'on';                
            end
            
            if app.ModelSelectListBox.Value == "IBI"
                startApp(app.CallingApp, ...
                     strcat(app.MapPath, '\', ...
                            app.ModelSelectListBox.Value, '\', ...
                            app.DateSelectListBox.Value, '\', ...
                            app.MapSelectListBox.Value), ...
                     app.ModelSelectListBox.Value);
            elseif app.ModelSelectListBox.Value == "NLOM"
                startApp(app.CallingApp, ...
                         [strcat(app.MapPath, '\', ...
                                 app.ModelSelectListBox.Value, '\', ...
                                 app.DateSelectListBox.Value, '\', ...
                                 app.MapSelectListBox.Value(1)) ...
                          strcat(app.MapPath, '\', ...
                                 app.ModelSelectListBox.Value, '\', ...
                                 app.DateSelectListBox.Value, '\', ...
                                 app.MapSelectListBox.Value(2))], ...
                         app.ModelSelectListBox.Value);
            end
            resetAreasAndTrails(app.CallingApp);
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            try
                app.CallingApp.LoadMapMenu.Enable = 'on';                
            end
            delete(app)
        end

        % Close request function: MapSelectorUIFigure
        function MapSelectorUIFigureCloseRequest(app, event)
            try
                app.CallingApp.LoadMapMenu.Enable = 'on';                
            end
            
            if app.Start
                delete(app.CallingApp)
            end
            
            delete(app)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapSelectorUIFigure = uifigure('Visible', 'off');
            app.MapSelectorUIFigure.Name = 'Map Selector';
            app.MapSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MapSelectorUIFigureCloseRequest, true);
            app.ModelSelectorPanel = uipanel(app.MapSelectorUIFigure);
            app.ModelSelectorPanel.Title = 'Model Selector';
            app.ModelSelectListBox = uilistbox(app.ModelSelectorPanel);
            app.ModelSelectListBox.Items = {'...'};
            app.ModelSelectListBox.ValueChangedFcn = createCallbackFcn(app, @ModelSelectListBoxValueChanged, true);
            app.ModelSelectListBox.Value = '...';
            app.DateSelectorPanel = uipanel(app.MapSelectorUIFigure);
            app.DateSelectorPanel.Title = 'Date Selector';
            app.DateSelectListBox = uilistbox(app.DateSelectorPanel);
            app.DateSelectListBox.Items = {'...'};
            app.DateSelectListBox.ValueChangedFcn = createCallbackFcn(app, @DateSelectListBoxValueChanged, true);
            app.DateSelectListBox.Value = '...';
            app.SelectmapButton = uibutton(app.MapSelectorUIFigure, 'push');
            app.SelectmapButton.ButtonPushedFcn = createCallbackFcn(app, @SelectmapButtonPushed, true);
            app.SelectmapButton.Text = 'Select map';
            app.MapSelectorPanel = uipanel(app.MapSelectorUIFigure);
            app.MapSelectorPanel.Title = 'Map Selector';
            app.MapSelectListBox = uilistbox(app.MapSelectorPanel);
            app.MapSelectListBox.Items = {'...'};
            app.MapSelectListBox.Multiselect = 'on';
            app.MapSelectListBox.ValueChangedFcn = createCallbackFcn(app, @MapSelectListBoxValueChanged, true);
            app.MapSelectListBox.Value = {'...'};
            app.CancelButton = uibutton(app.MapSelectorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MapSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapSelectorDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapSelectorUIFigure)
        end
    end
end