classdef OceanViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure              matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadMapMenu             matlab.ui.container.Menu
        Panel                   matlab.ui.container.Panel
        AreasAndTrailsTabGroup  matlab.ui.container.TabGroup
        AreasTab                matlab.ui.container.Tab
        OpenAreaWindowButton    matlab.ui.control.Button
        DeleteAreaButton        matlab.ui.control.Button
        NewAreaButton           matlab.ui.control.Button
        AreasListBox            matlab.ui.control.ListBox
        TrailsTab               matlab.ui.container.Tab
        OpenTrailWindowButton   matlab.ui.control.Button
        DeleteTrailButton       matlab.ui.control.Button
        NewTrailButton          matlab.ui.control.Button
        TrailsListBox           matlab.ui.control.ListBox
        DepthControllerPanel    matlab.ui.container.Panel
        ShallowerButton         matlab.ui.control.Button
        DeeperButton            matlab.ui.control.Button
        TimeControllerPanel     matlab.ui.container.Panel
        LaterButton             matlab.ui.control.Button
        SoonerButton            matlab.ui.control.Button
        AxesTabGroup            matlab.ui.container.TabGroup
        CurrentTab              matlab.ui.container.Tab
        CurrentAxes             matlab.ui.control.UIAxes
        TemperatureTab          matlab.ui.container.Tab
        TemperatureAxes         matlab.ui.control.UIAxes
        SalinityTab             matlab.ui.container.Tab
        SalinityAxes            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MapDialog % Map selector dialog
        MapsPath = "" % General maps location
        MapData = struct % Container of all currently used  map data
        MaxDepth % Max value allowed to DepthCounter
        DepthCounter % Index to iterate between depths
        DepthText % Depth indicator
        MaxTime % Max value allowed to TimeCounter
        TimeCounter % Index to iterate between times
        TimeText % Time indicator
        Areas = struct % Container of all 5 possible areas
        Trails = struct % Container of all 5 possible traisl
        SelectedTab % Currently active tab
    end

    methods (Access = public)

        function startApp(app, fp, md)
            app.FileMenu.Enable = 'on';
            app.MainFigure.Visible = 'on';

            flags = struct('vo', 0, 'uo', 0, 'so', 0, 'thetao', 0, 'depth', 0, 'time', 0);
            aFlags = [0 0 0 0 0];

            app.MapData.model = md;

            if md == "IBI"

                fp = fp{1};

                ncid = netcdf.open(fp, 'NOWRITE');
                gid = netcdf.inqNcid(ncid, 'grid1');
                for varId = netcdf.inqVarIDs(gid)
                    if strcmp(netcdf.inqVar(gid, varId), 'latitude')
                        app.MapData.latitude = netcdf.getVar(gid, varId, 'double' );
                    elseif strcmp(netcdf.inqVar(gid, varId), 'longitude')
                        app.MapData.longitude = netcdf.getVar(gid, varId, 'double' );
                    elseif strcmp(netcdf.inqVar(gid, varId), 'vo')
                        flags.vo = 1;
                        app.MapData.vo = ncread(fp, "vo");
                    elseif strcmp(netcdf.inqVar(gid, varId), 'uo')
                        flags.uo = 1;
                        app.MapData.uo = ncread(fp, "uo");
                    elseif strcmp(netcdf.inqVar(gid, varId), 'so')
                        flags.so = 1;
                        app.MapData.so = ncread(fp, "so");
                    elseif strcmp(netcdf.inqVar(gid, varId), 'thetao')
                        flags.thetao = 1;
                        app.MapData.thetao = ncread(fp, "thetao");
                    elseif strcmp(netcdf.inqVar(gid, varId), 'time')
                        flags.time = 1;
                        app.MapData.time = ncread(fp, "time");
                        app.TimeCounter = 1;
                        app.MaxTime = length(app.MapData.time);
                        t = double(app.MapData.time(app.TimeCounter))/24 + datenum('1950-01-01 00:00:00');
                        app.TimeText = strcat("(Date: ", datestr(t, 'dd-mm-yyyy HH:MM:SS'), ")");
                    elseif strcmp(netcdf.inqVar(gid, varId), 'depth')
                        flags.depth = 1;
                        app.MapData.depth = ncread(fp, "depth");
                        app.DepthCounter = 1;
                        app.MaxDepth = length(app.MapData.depth);
                        app.DepthText = strcat("(Depth level: ", string(app.MapData.depth(app.DepthCounter)), ")");
                    end
                end
                netcdf.close(ncid);

            elseif md == "NLOM"

                ncid1 = netcdf.open(fp{1}, 'NOWRITE');
                ncid2 = netcdf.open(fp{2}, 'NOWRITE');
                gid1 = netcdf.inqNcid(ncid1, 'grid1');
                gid2 = netcdf.inqNcid(ncid2, 'grid1');

                for varId = netcdf.inqVarIDs(gid1)
                    if startsWith(netcdf.inqVar(gid1, varId), 'LAT')
                        app.MapData.latitude = netcdf.getVar(gid1, varId, 'double' );
                    elseif startsWith(netcdf.inqVar(gid1, varId), 'LON')
                        app.MapData.longitude = netcdf.getVar(gid1, varId, 'double' );
                    elseif strcmp(netcdf.inqVar(gid1, varId), 'V1')
                        flags.vo = 1;
                        app.MapData.vo = ncread(fp{1}, "V1");
                    elseif strcmp(netcdf.inqVar(gid1, varId), 'U1')
                        flags.uo = 1;
                        app.MapData.uo = ncread(fp{1}, "U1");
                    elseif strcmp(netcdf.inqVar(gid1, varId), 'TIME')
                        flags.time = 1;
                        app.MapData.time = ncread(fp{1}, "TIME");
                        app.TimeCounter = 1;
                        app.MaxTime = length(app.MapData.time);
                        t = double(app.MapData.time(app.TimeCounter)) + datenum('00-00-0001 00:00:00');
                        app.TimeText = strcat("(Date: ", datestr(t, 'dd-mm-yyyy HH:MM:SS'), ")");
                    elseif strcmp(netcdf.inqVar(gid1, varId), 'LEV')
                        flags.depth = 1;
                        app.MapData.depth = ncread(fp{1}, "LEV");
                        app.DepthCounter = 1;
                        app.MaxDepth = length(app.MapData.depth);
                        app.DepthText = strcat("(Depth level: ", string(app.MapData.depth(app.DepthCounter)), ")");
                    end
                end

                for varId = netcdf.inqVarIDs(gid2)
                    if strcmp(netcdf.inqVar(gid2, varId), 'V1')
                        flags.vo = 1;
                        app.MapData.vo = ncread(fp{2}, "V1");
                    elseif strcmp(netcdf.inqVar(gid2, varId), 'U1')
                        flags.uo = 1;
                        app.MapData.uo = ncread(fp{2}, "U1");
                    end
                end

                netcdf.close(ncid1);
                netcdf.close(ncid2);

            end

            app.MapData.flags = flags;
            app.MapData.model = md;

            app.Areas.flags = aFlags;
            app.Areas.dialogflags = aFlags;
            app.Trails.flags = aFlags;
            app.Trails.dialogflags = aFlags;

            updateAll(app);
        end

        function resetOpenAreaButton(app, color)
            if color
                app.Areas.dialogflags(color) = 0;
            end
            switch app.AreasListBox.Value
                case "Red"
                    if app.Areas.dialogflags(1)
                        app.OpenAreaWindowButton.Enable = 'off';
                    else
                        app.OpenAreaWindowButton.Enable = 'on';
                    end
                case "Green"
                    if app.Areas.dialogflags(2)
                        app.OpenAreaWindowButton.Enable = 'off';
                    else
                        app.OpenAreaWindowButton.Enable = 'on';
                    end
                case "Blue"
                    if app.Areas.dialogflags(3)
                        app.OpenAreaWindowButton.Enable = 'off';
                    else
                        app.OpenAreaWindowButton.Enable = 'on';
                    end
                case "Yellow"
                    if app.Areas.dialogflags(4)
                        app.OpenAreaWindowButton.Enable = 'off';
                    else
                        app.OpenAreaWindowButton.Enable = 'on';
                    end
                case "Black"
                    if app.Areas.dialogflags(5)
                        app.OpenAreaWindowButton.Enable = 'off';
                    else
                        app.OpenAreaWindowButton.Enable = 'on';
                    end
                otherwise
                    app.OpenAreaWindowButton.Enable = "off";
            end
        end

        function resetOpenTrailButton(app, color)
            if color
                app.Trails.dialogflags(color) = 0;
            end
            switch app.TrailsListBox.Value
                case "Red"
                    if app.Trails.dialogflags(1)
                        app.OpenTrailWindowButton.Enable = 'off';
                    else
                        app.OpenTrailWindowButton.Enable = 'on';
                    end
                case "Green"
                    if app.Trails.dialogflags(2)
                        app.OpenTrailWindowButton.Enable = 'off';
                    else
                        app.OpenTrailWindowButton.Enable = 'on';
                    end
                case "Blue"
                    if app.Trails.dialogflags(3)
                        app.OpenTrailWindowButton.Enable = 'off';
                    else
                        app.OpenTrailWindowButton.Enable = 'on';
                    end
                case "Yellow"
                    if app.Trails.dialogflags(4)
                        app.OpenTrailWindowButton.Enable = 'off';
                    else
                        app.OpenTrailWindowButton.Enable = 'on';
                    end
                case "Black"
                    if app.Trails.dialogflags(5)
                        app.OpenTrailWindowButton.Enable = 'off';
                    else
                        app.OpenTrailWindowButton.Enable = 'on';
                    end
                otherwise
                    app.OpenTrailWindowButton.Enable = "off";
            end
        end

        function resetAreasAndTrails(app)

            try delete(app.Areas.red.current), end
            try delete(app.Areas.red.salinity), end
            try delete(app.Areas.red.temperature), end
            try delete(app.Areas.red.dialog), end
            try delete(app.Areas.red.listener.current), end
            try delete(app.Areas.red.listener.salinity), end
            try delete(app.Areas.red.listener.temperature), end
            app.Areas.flags(1) = 0;
            app.Areas.dialogflags(1) = 0;

            try delete(app.Areas.green.current), end
            try delete(app.Areas.green.salinity), end
            try delete(app.Areas.green.temperature), end
            try delete(app.Areas.green.dialog), end %#ok<*TRYNC>
            try delete(app.Areas.green.listener.current), end
            try delete(app.Areas.green.listener.salinity), end
            try delete(app.Areas.green.listener.temperature), end
            app.Areas.flags(2) = 0;
            app.Areas.dialogflags(2) = 0;

            try delete(app.Areas.blue.current), end
            try delete(app.Areas.blue.salinity), end
            try delete(app.Areas.blue.temperature), end
            try delete(app.Areas.blue.dialog), end
            try delete(app.Areas.blue.listener.current), end
            try delete(app.Areas.blue.listener.salinity), end
            try delete(app.Areas.blue.listener.temperature), end
            app.Areas.flags(3) = 0;
            app.Areas.dialogflags(3) = 0;

            try delete(app.Areas.yellow.current), end
            try delete(app.Areas.yellow.salinity), end
            try delete(app.Areas.yellow.temperature), end
            try delete(app.Areas.yellow.dialog), end
            try delete(app.Areas.yellow.listener.current), end
            try delete(app.Areas.yellow.listener.salinity), end
            try delete(app.Areas.yellow.listener.temperature), end
            app.Areas.flags(4) = 0;
            app.Areas.dialogflags(4) = 0;

            try delete(app.Areas.black.current), end
            try delete(app.Areas.black.salinity), end
            try delete(app.Areas.black.temperature), end
            try delete(app.Areas.black.dialog), end
            try delete(app.Areas.black.listener.current), end
            try delete(app.Areas.black.listener.salinity), end
            try delete(app.Areas.black.listener.temperature), end
            app.Areas.flags(5) = 0;
            app.Areas.dialogflags(5) = 0;

            app.AreasListBox.Value = "...";
            app.DeleteAreaButton.Enable = "off";
            app.OpenAreaWindowButton.Enable = 'off';

            updateAreasListBox(app);


            try delete(app.Trails.red.current), end
            try delete(app.Trails.red.salinity), end
            try delete(app.Trails.red.temperature), end
            try delete(app.Trails.red.dialog), end
            try delete(app.Trails.red.listener.current), end
            try delete(app.Trails.red.listener.salinity), end
            try delete(app.Trails.red.listener.temperature), end
            app.Trails.flags(1) = 0;
            app.Trails.dialogflags(1) = 0;

            try delete(app.Trails.green.current), end
            try delete(app.Trails.green.salinity), end
            try delete(app.Trails.green.temperature), end
            try delete(app.Trails.green.dialog), end %#ok<*TRYNC>
            try delete(app.Trails.green.listener.current), end
            try delete(app.Trails.green.listener.salinity), end
            try delete(app.Trails.green.listener.temperature), end
            app.Trails.flags(2) = 0;
            app.Trails.dialogflags(2) = 0;

            try delete(app.Trails.blue.current), end
            try delete(app.Trails.blue.salinity), end
            try delete(app.Trails.blue.temperature), end
            try delete(app.Trails.blue.dialog), end
            try delete(app.Trails.blue.listener.current), end
            try delete(app.Trails.blue.listener.salinity), end
            try delete(app.Trails.blue.listener.temperature), end
            app.Trails.flags(3) = 0;
            app.Trails.dialogflags(3) = 0;

            try delete(app.Trails.yellow.current), end
            try delete(app.Trails.yellow.salinity), end
            try delete(app.Trails.yellow.temperature), end
            try delete(app.Trails.yellow.dialog), end
            try delete(app.Trails.yellow.listener.current), end
            try delete(app.Trails.yellow.listener.salinity), end
            try delete(app.Trails.yellow.listener.temperature), end
            app.Trails.flags(4) = 0;
            app.Trails.dialogflags(4) = 0;

            try delete(app.Trails.black.current), end
            try delete(app.Trails.black.salinity), end
            try delete(app.Trails.black.temperature), end
            try delete(app.Trails.black.dialog), end
            try delete(app.Trails.black.listener.current), end
            try delete(app.Trails.black.listener.salinity), end
            try delete(app.Trails.black.listener.temperature), end
            app.Trails.flags(5) = 0;
            app.Trails.dialogflags(5) = 0;

            app.TrailsListBox.Value = "...";
            app.DeleteTrailButton.Enable = "off";
            app.OpenTrailWindowButton.Enable = 'off';

            updateTrailsListBox(app);
        end
    end

    methods (Access = private)

        function updateAll(app)

            app.CurrentTab.Parent = [];
            app.SalinityTab.Parent = [];
            app.TemperatureTab.Parent = [];

            updateAxes(app, 1);

            app.AxesTabGroup.Visible = "on";

            app.DepthControllerPanel.Visible = "on";
            if app.MapData.flags.depth
                app.DepthControllerPanel.Enable = "on";
            else
                app.DepthControllerPanel.Enable = "off";
            end

            app.TimeControllerPanel.Visible = "on";
            if app.MapData.flags.time
                app.TimeControllerPanel.Enable = "on";
            else
                app.TimeControllerPanel.Enable = "off";
            end

            app.AreasAndTrailsTabGroup.Visible = "on";

        end

        function updateAxes(app, fromLoad)
            if app.MapData.flags.uo && app.MapData.flags.vo
                app.CurrentTab.Parent = app.AxesTabGroup;
                if fromLoad
                    app.SelectedTab = "Current";
                    fromLoad = 0;
                end
                updateCurrentAxes(app);

            end
            if app.MapData.flags.thetao
                app.TemperatureTab.Parent = app.AxesTabGroup;
                if fromLoad
                    app.SelectedTab = "Temperature";
                    fromLoad = 0;
                end
                updateTemperatureAxes(app);
            end
            if app.MapData.flags.so
                app.SalinityTab.Parent = app.AxesTabGroup;
                if fromLoad
                    app.SelectedTab = "Salinity";
                end
                updateSalinityAxes(app);
            end
        end

        function updateCurrentAxes(app)
            if app.MapData.flags.depth && app.MapData.flags.time
                u = squeeze(app.MapData.uo(:,:,app.DepthCounter,app.TimeCounter));
                v = squeeze(app.MapData.vo(:,:,app.DepthCounter,app.TimeCounter));
                title(app.CurrentAxes, strcat("Current Data ", app.DepthText, " ", app.TimeText));

            elseif app.MapData.flags.depth
                u = squeeze(app.MapData.uo(:,:,app.DepthCounter));
                v = squeeze(app.MapData.vo(:,:,app.DepthCounter));
                title(app.CurrentAxes, strcat("Current Data ", app.DepthText));

            elseif app.MapData.flags.time
                u = squeeze(app.MapData.uo(:,:,app.TimeCounter));
                v = squeeze(app.MapData.vo(:,:,app.TimeCounter));
                title(app.CurrentAxes, strcat("Current Data ", app.TimeText));

            else
                u = squeeze(app.MapData.uo(:,:));
                v = squeeze(app.MapData.vo(:,:));
                title(app.CurrentAxes, "Current Data");

            end

            [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

            uv = (u + v)./2;

            pcolor(app.CurrentAxes, lon, lat, uv');
            hold(app.CurrentAxes, 'on');

            step = 2;

            quiver(app.CurrentAxes, ...
                lon(1:step:end,1:step:end), ...
                lat(1:step:end,1:step:end), ...
                u(1:step:end,1:step:end)', ...
                v(1:step:end,1:step:end)', ...
                step*0.5, ...
                'Color', [0 0 1]);

            shading(app.CurrentAxes, 'interp');
            colormap(app.CurrentAxes, 'white');
            axis(app.CurrentAxes, [min(app.MapData.longitude) ...
                max(app.MapData.longitude) ...
                min(app.MapData.latitude) ...
                max(app.MapData.latitude)]);
        end

        function updateSalinityAxes(app)
            if app.MapData.flags.depth && app.MapData.flags.time
                so = squeeze(app.MapData.so(:,:,app.DepthCounter,app.TimeCounter));
                title(app.SalinityAxes, strcat("Salinity Data ", app.DepthText, " ", app.TimeText));

            elseif app.MapData.flags.depth
                so = squeeze(app.MapData.so(:,:,app.DepthCounter));
                title(app.SalinityAxes, strcat("Salinity Data ", app.DepthText));

            elseif app.MapData.flags.time
                so = squeeze(app.MapData.so(:,:,app.TimeCounter));
                title(app.SalinityAxes, strcat("Salinity Data ", app.TimeText));

            else
                so = squeeze(app.MapData.so(:,:));
                title(app.SalinityAxes, "Salinity Data");

            end

            [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

            pcolor(app.SalinityAxes, lon, lat, so');
            shading(app.SalinityAxes, 'interp');
            axis(app.SalinityAxes, [min(app.MapData.longitude) ...
                max(app.MapData.longitude) ...
                min(app.MapData.latitude) ...
                max(app.MapData.latitude)]);

            colormap(app.SalinityAxes, 'cool');
            colorbar(app.SalinityAxes);

        end

        function updateTemperatureAxes(app)
            if app.MapData.flags.depth && app.MapData.flags.time
                thetao = squeeze(app.MapData.thetao(:,:,app.DepthCounter,app.TimeCounter));
                title(app.TemperatureAxes, strcat("Temperature Data ", app.DepthText, " ", app.TimeText));

            elseif app.MapData.flags.depth
                thetao = squeeze(app.MapData.thetao(:,:,app.DepthCounter));
                title(app.TemperatureAxes, strcat("Temperature Data ", app.DepthText));

            elseif app.MapData.flags.time
                thetao = squeeze(app.MapData.thetao(:,:,app.TimeCounter));
                title(app.TemperatureAxes, strcat("Temperature Data ", app.TimeText));

            else
                thetao = squeeze(app.MapData.thetao(:,:));
                title(app.TemperatureAxes, "Temperature Data");

            end

            [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

            p1 = pcolor(app.TemperatureAxes, lon, lat, thetao'); %#ok<NASGU>

            shading(app.TemperatureAxes, 'interp');
            axis(app.TemperatureAxes, [min(app.MapData.longitude) ...
                max(app.MapData.longitude) ...
                min(app.MapData.latitude) ...
                max(app.MapData.latitude)]);

            colormap(app.TemperatureAxes, 'turbo');
            colorbar(app.TemperatureAxes);
        end

        function updateTrails(app, isNew, tab, color)
            if (tab ~= "Current") && (app.MapData.flags.vo && app.MapData.flags.uo)
                switch color
                    case 1
                        if not(isNew)
                            app.Trails.red.current.Position = app.Trails.red.vertex;
                        else
                            app.Trails.red.current = drawpolyline(app.CurrentAxes, 'Color', 'r', 'Position', app.Trails.red.vertex, 'Deletable', false);
                            app.Trails.red.listener.current = addlistener(app.Trails.red.current, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Current"));
                        end
                    case 2
                        if not(isNew)
                            app.Trails.green.current.Position = app.Trails.green.vertex;
                        else
                            app.Trails.green.current = drawpolyline(app.CurrentAxes, 'Color', 'g', 'Position', app.Trails.green.vertex, 'Deletable', false);
                            app.Trails.green.listener.current = addlistener(app.Trails.green.current, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Current"));
                        end
                    case 3
                        if not(isNew)
                            app.Trails.blue.current.Position = app.Trails.blue.vertex;
                        else
                            app.Trails.blue.current = drawpolyline(app.CurrentAxes, 'Color', 'b', 'Position', app.Trails.blue.vertex, 'Deletable', false);
                            app.Trails.blue.listener.current = addlistener(app.Trails.blue.current, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Current"));
                        end
                    case 4
                        if not(isNew)
                            app.Trails.yellow.current.Position = app.Trails.yellow.vertex;
                        else
                            app.Trails.yellow.current = drawpolyline(app.CurrentAxes, 'Color', 'y', 'Position', app.Trails.yellow.vertex, 'Deletable', false);
                            app.Trails.yellow.listener.current = addlistener(app.Trails.yellow.current, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Current"));
                        end
                    case 5
                        if not(isNew)
                            app.Trails.black.current.Position = app.Trails.black.vertex;
                        else
                            app.Trails.black.current = drawpolyline(app.CurrentAxes, 'Color', 'k', 'Position', app.Trails.black.vertex, 'Deletable', false);
                            app.Trails.black.listener.current = addlistener(app.Trails.black.current, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Current"));
                        end
                end
            end
            if (tab ~= "Salinity") && app.MapData.flags.so
                switch color
                    case 1
                        if not(isNew)
                            app.Trails.red.salinity.Position = app.Trails.red.vertex;
                        else
                            app.Trails.red.salinity = drawpolyline(app.SalinityAxes, 'Color', 'r', 'Position', app.Trails.red.vertex, 'Deletable', false);
                            app.Trails.red.listener.salinity = addlistener(app.Trails.red.salinity, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Salinity"));
                        end
                    case 2
                        if not(isNew)
                            app.Trails.green.salinity.Position = app.Trails.green.vertex;
                        else
                            app.Trails.green.salinity = drawpolyline(app.SalinityAxes, 'Color', 'g', 'Position', app.Trails.green.vertex, 'Deletable', false);
                            app.Trails.green.listener.salinity = addlistener(app.Trails.green.salinity, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Salinity"));
                        end
                    case 3
                        if not(isNew)
                            app.Trails.blue.salinity.Position = app.Trails.blue.vertex;
                        else
                            app.Trails.blue.salinity = drawpolyline(app.SalinityAxes, 'Color', 'b', 'Position', app.Trails.blue.vertex, 'Deletable', false);
                            app.Trails.blue.listener.salinity = addlistener(app.Trails.blue.salinity, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Salinity"));
                        end
                    case 4
                        if not(isNew)
                            app.Trails.yellow.salinity.Position = app.Trails.yellow.vertex;
                        else
                            app.Trails.yellow.salinity = drawpolyline(app.SalinityAxes, 'Color', 'y', 'Position', app.Trails.yellow.vertex, 'Deletable', false);
                            app.Trails.yellow.listener.salinity = addlistener(app.Trails.yellow.salinity, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Salinity"));
                        end
                    case 5
                        if not(isNew)
                            app.Trails.black.salinity.Position = app.Trails.black.vertex;
                        else
                            app.Trails.black.salinity = drawpolyline(app.SalinityAxes, 'Color', 'k', 'Position', app.Trails.black.vertex, 'Deletable', false);
                            app.Trails.black.listener.salinity = addlistener(app.Trails.black.salinity, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Salinity"));
                        end
                end
            end
            if (tab ~= "Temperature") && app.MapData.flags.thetao
                switch color
                    case 1
                        if not(isNew)
                            app.Trails.red.temperature.Position = app.Trails.red.vertex;
                        else
                            app.Trails.red.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'r', 'Position', app.Trails.red.vertex, 'Deletable', false);
                            app.Trails.red.listener.temperature = addlistener(app.Trails.red.temperature, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Temperature"));
                        end
                    case 2
                        if not(isNew)
                            app.Trails.green.temperature.Position = app.Trails.green.vertex;
                        else
                            app.Trails.green.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'g', 'Position', app.Trails.green.vertex, 'Deletable', false);
                            app.Trails.green.listener.temperature = addlistener(app.Trails.green.temperature, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Temperature"));
                        end
                    case 3
                        if not(isNew)
                            app.Trails.blue.temperature.Position = app.Trails.blue.vertex;
                        else
                            app.Trails.blue.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'b', 'Position', app.Trails.blue.vertex, 'Deletable', false);
                            app.Trails.blue.listener.temperature = addlistener(app.Trails.blue.temperature, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Temperature"));
                        end
                    case 4
                        if not(isNew)
                            app.Trails.yellow.temperature.Position = app.Trails.yellow.vertex;
                        else
                            app.Trails.yellow.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'y', 'Position', app.Trails.yellow.vertex, 'Deletable', false);
                            app.Trails.yellow.listener.temperature = addlistener(app.Trails.yellow.temperature, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Temperature"));
                        end
                    case 5
                        if not(isNew)
                            app.Trails.black.temperature.Position = app.Trails.black.vertex;
                        else
                            app.Trails.black.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'k', 'Position', app.Trails.black.vertex, 'Deletable', false);
                            app.Trails.black.listener.temperature = addlistener(app.Trails.black.temperature, 'ROIMoved', @(src,event)updateTrailDialog(app, color, "Temperature"));
                        end
                end
            end

            updateTrailsListBox(app);

        end

        function updateTrailsListBox(app)
            text = "...";

            if app.Trails.flags(1)
                text = [text "Red"];
            end
            if app.Trails.flags(2)
                text = [text "Green"];
            end
            if app.Trails.flags(3)
                text = [text "Blue"];
            end
            if app.Trails.flags(4)
                text = [text "Yellow"];
            end
            if app.Trails.flags(5)
                text = [text "Black"];
            end

            app.TrailsListBox.Items = text;

        end

        function updateTrailDialog(app, color, tab)

            switch color
                case 1

                    switch tab
                        case "Current"
                            app.Trails.red.vertex = app.Trails.red.current.Position;
                        case "Salinity"
                            app.Trails.red.vertex = app.Trails.red.salinity.Position;
                        case "Temperature"
                            app.Trails.red.vertex = app.Trails.red.temperature.Position;
                    end

                    updateTrails(app, 0, tab, 1);

                    if app.Trails.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inLinearROI(app, app.Trails.red.current.Position, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Trails.red.dialog, data);
                    end

                case 2

                    switch tab
                        case "Current"
                            app.Trails.green.vertex = app.Trails.green.current.Position;
                        case "Salinity"
                            app.Trails.green.vertex = app.Trails.green.salinity.Position;
                        case "Temperature"
                            app.Trails.green.vertex = app.Trails.green.temperature.Position;
                    end

                    updateTrails(app, 0, tab, 2);

                    if app.Trails.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inLinearROI(app, app.Trails.green.current.Position, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Trails.green.dialog, data);

                    end

                case 3

                    switch tab
                        case "Current"
                            app.Trails.blue.vertex = app.Trails.blue.current.Position;
                        case "Salinity"
                            app.Trails.blue.vertex = app.Trails.blue.salinity.Position;
                        case "Temperature"
                            app.Trails.blue.vertex = app.Trails.blue.temperature.Position;
                    end

                    updateTrails(app, 0, tab, 3);

                    if app.Trails.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inLinearROI(app, app.Trails.blue.current.Position, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Trails.blue.dialog, data);

                    end

                case 4

                    switch tab
                        case "Current"
                            app.Trails.yellow.vertex = app.Trails.yellow.current.Position;
                        case "Salinity"
                            app.Trails.yellow.vertex = app.Trails.yellow.salinity.Position;
                        case "Temperature"
                            app.Trails.yellow.vertex = app.Trails.yellow.temperature.Position;
                    end

                    updateTrails(app, 0, tab, 4);

                    if app.Trails.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inLinearROI(app, app.Trails.yellow.current.Position, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Trails.yellow.dialog, data);

                    end

                case 5

                    switch tab
                        case "Current"
                            app.Trails.black.vertex = app.Trails.black.current.Position;
                        case "Salinity"
                            app.Trails.black.vertex = app.Trails.black.salinity.Position;
                        case "Temperature"
                            app.Trails.black.vertex = app.Trails.black.temperature.Position;
                    end

                    updateTrails(app, 0, tab, 5);

                    if app.Trails.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inLinearROI(app, app.Trails.black.current.Position, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Trails.black.dialog, data);

                    end
            end
        end

        function updateAreas(app, isNew, tab, color)
            if (tab ~= "Current") && (app.MapData.flags.vo && app.MapData.flags.uo)
                switch color
                    case 1
                        if not(isNew)
                            app.Areas.red.current.Position = app.Areas.red.vertex;
                        else
                            app.Areas.red.current = drawpolygon(app.CurrentAxes, 'Color', 'r', 'Position', app.Areas.red.vertex, 'Deletable', false);
                            app.Areas.red.listener.current = addlistener(app.Areas.red.current, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Current"));
                        end
                    case 2
                        if not(isNew)
                            app.Areas.green.current.Position = app.Areas.green.vertex;
                        else
                            app.Areas.green.current = drawpolygon(app.CurrentAxes, 'Color', 'g', 'Position', app.Areas.green.vertex, 'Deletable', false);
                            app.Areas.green.listener.current = addlistener(app.Areas.green.current, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Current"));
                        end
                    case 3
                        if not(isNew)
                            app.Areas.blue.current.Position = app.Areas.blue.vertex;
                        else
                            app.Areas.blue.current = drawpolygon(app.CurrentAxes, 'Color', 'b', 'Position', app.Areas.blue.vertex, 'Deletable', false);
                            app.Areas.blue.listener.current = addlistener(app.Areas.blue.current, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Current"));
                        end
                    case 4
                        if not(isNew)
                            app.Areas.yellow.current.Position = app.Areas.yellow.vertex;
                        else
                            app.Areas.yellow.current = drawpolygon(app.CurrentAxes, 'Color', 'y', 'Position', app.Areas.yellow.vertex, 'Deletable', false);
                            app.Areas.yellow.listener.current = addlistener(app.Areas.yellow.current, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Current"));
                        end
                    case 5
                        if not(isNew)
                            app.Areas.black.current.Position = app.Areas.black.vertex;
                        else
                            app.Areas.black.current = drawpolygon(app.CurrentAxes, 'Color', 'k', 'Position', app.Areas.black.vertex, 'Deletable', false);
                            app.Areas.black.listener.current = addlistener(app.Areas.black.current, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Current"));
                        end
                end
            end
            if (tab ~= "Salinity") && app.MapData.flags.so
                switch color
                    case 1
                        if not(isNew)
                            app.Areas.red.salinity.Position = app.Areas.red.vertex;
                        else
                            app.Areas.red.salinity = drawpolygon(app.SalinityAxes, 'Color', 'r', 'Position', app.Areas.red.vertex, 'Deletable', false);
                            app.Areas.red.listener.salinity = addlistener(app.Areas.red.salinity, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Salinity"));
                        end
                    case 2
                        if not(isNew)
                            app.Areas.green.salinity.Position = app.Areas.green.vertex;
                        else
                            app.Areas.green.salinity = drawpolygon(app.SalinityAxes, 'Color', 'g', 'Position', app.Areas.green.vertex, 'Deletable', false);
                            app.Areas.green.listener.salinity = addlistener(app.Areas.green.salinity, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Salinity"));
                        end
                    case 3
                        if not(isNew)
                            app.Areas.blue.salinity.Position = app.Areas.blue.vertex;
                        else
                            app.Areas.blue.salinity = drawpolygon(app.SalinityAxes, 'Color', 'b', 'Position', app.Areas.blue.vertex, 'Deletable', false);
                            app.Areas.blue.listener.salinity = addlistener(app.Areas.blue.salinity, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Salinity"));
                        end
                    case 4
                        if not(isNew)
                            app.Areas.yellow.salinity.Position = app.Areas.yellow.vertex;
                        else
                            app.Areas.yellow.salinity = drawpolygon(app.SalinityAxes, 'Color', 'y', 'Position', app.Areas.yellow.vertex, 'Deletable', false);
                            app.Areas.yellow.listener.salinity = addlistener(app.Areas.yellow.salinity, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Salinity"));
                        end
                    case 5
                        if not(isNew)
                            app.Areas.black.salinity.Position = app.Areas.black.vertex;
                        else
                            app.Areas.black.salinity = drawpolygon(app.SalinityAxes, 'Color', 'k', 'Position', app.Areas.black.vertex, 'Deletable', false);
                            app.Areas.black.listener.salinity = addlistener(app.Areas.black.salinity, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Salinity"));
                        end
                end
            end
            if (tab ~= "Temperature") && app.MapData.flags.thetao
                switch color
                    case 1
                        if not(isNew)
                            app.Areas.red.temperature.Position = app.Areas.red.vertex;
                        else
                            app.Areas.red.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'r', 'Position', app.Areas.red.vertex, 'Deletable', false);
                            app.Areas.red.listener.temperature = addlistener(app.Areas.red.temperature, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Temperature"));
                        end
                    case 2
                        if not(isNew)
                            app.Areas.green.temperature.Position = app.Areas.green.vertex;
                        else
                            app.Areas.green.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'g', 'Position', app.Areas.green.vertex, 'Deletable', false);
                            app.Areas.green.listener.temperature = addlistener(app.Areas.green.temperature, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Temperature"));
                        end
                    case 3
                        if not(isNew)
                            app.Areas.blue.temperature.Position = app.Areas.blue.vertex;
                        else
                            app.Areas.blue.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'b', 'Position', app.Areas.blue.vertex, 'Deletable', false);
                            app.Areas.blue.listener.temperature = addlistener(app.Areas.blue.temperature, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Temperature"));
                        end
                    case 4
                        if not(isNew)
                            app.Areas.yellow.temperature.Position = app.Areas.yellow.vertex;
                        else
                            app.Areas.yellow.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'y', 'Position', app.Areas.yellow.vertex, 'Deletable', false);
                            app.Areas.yellow.listener.temperature = addlistener(app.Areas.yellow.temperature, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Temperature"));
                        end
                    case 5
                        if not(isNew)
                            app.Areas.black.temperature.Position = app.Areas.black.vertex;
                        else
                            app.Areas.black.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'k', 'Position', app.Areas.black.vertex, 'Deletable', false);
                            app.Areas.black.listener.temperature = addlistener(app.Areas.black.temperature, 'ROIMoved', @(src,event)updateAreaDialog(app, color, "Temperature"));
                        end
                end
            end

            updateAreasListBox(app);

        end

        function updateAreasListBox(app)
            text = "...";

            if app.Areas.flags(1)
                text = [text "Red"];
            end
            if app.Areas.flags(2)
                text = [text "Green"];
            end
            if app.Areas.flags(3)
                text = [text "Blue"];
            end
            if app.Areas.flags(4)
                text = [text "Yellow"];
            end
            if app.Areas.flags(5)
                text = [text "Black"];
            end

            app.AreasListBox.Items = text;

        end

        function updateAreaDialog(app, color, tab)

            switch color
                case 1

                    switch tab
                        case "Current"
                            app.Areas.red.vertex = app.Areas.red.current.Position;
                        case "Salinity"
                            app.Areas.red.vertex = app.Areas.red.salinity.Position;
                        case "Temperature"
                            app.Areas.red.vertex = app.Areas.red.temperature.Position;
                    end

                    updateAreas(app, 0, tab, 1);

                    if app.Areas.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inROI(app.Areas.red.current, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Areas.red.dialog, data);
                    end

                case 2

                    switch tab
                        case "Current"
                            app.Areas.green.vertex = app.Areas.green.current.Position;
                        case "Salinity"
                            app.Areas.green.vertex = app.Areas.green.salinity.Position;
                        case "Temperature"
                            app.Areas.green.vertex = app.Areas.green.temperature.Position;
                    end

                    updateAreas(app, 0, tab, 2);

                    if app.Areas.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inROI(app.Areas.green.current, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Areas.green.dialog, data);

                    end

                case 3

                    switch tab
                        case "Current"
                            app.Areas.blue.vertex = app.Areas.blue.current.Position;
                        case "Salinity"
                            app.Areas.blue.vertex = app.Areas.blue.salinity.Position;
                        case "Temperature"
                            app.Areas.blue.vertex = app.Areas.blue.temperature.Position;
                    end

                    updateAreas(app, 0, tab, 3);

                    if app.Areas.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inROI(app.Areas.blue.current, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Areas.blue.dialog, data);

                    end

                case 4

                    switch tab
                        case "Current"
                            app.Areas.yellow.vertex = app.Areas.yellow.current.Position;
                        case "Salinity"
                            app.Areas.yellow.vertex = app.Areas.yellow.salinity.Position;
                        case "Temperature"
                            app.Areas.yellow.vertex = app.Areas.yellow.temperature.Position;
                    end

                    updateAreas(app, 0, tab, 4);

                    if app.Areas.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inROI(app.Areas.yellow.current, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Areas.yellow.dialog, data);

                    end

                case 5

                    switch tab
                        case "Current"
                            app.Areas.black.vertex = app.Areas.black.current.Position;
                        case "Salinity"
                            app.Areas.black.vertex = app.Areas.black.salinity.Position;
                        case "Temperature"
                            app.Areas.black.vertex = app.Areas.black.temperature.Position;
                    end

                    updateAreas(app, 0, tab, 5);

                    if app.Areas.dialogflags(color)

                        [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

                        tf = inROI(app.Areas.black.current, lon, lat);

                        data = getDataFromTf(app, lon, lat, tf);

                        updateData(app.Areas.black.dialog, data);

                    end
            end
        end

        function data = getDataFromTf(app, lon, lat, tf)
            count = 1;
            data = struct;


            if app.MapData.flags.depth && app.MapData.flags.time
                if app.MapData.flags.uo && app.MapData.flags.vo
                    u = squeeze(app.MapData.uo(:,:,app.DepthCounter,app.TimeCounter));
                    v = squeeze(app.MapData.vo(:,:,app.DepthCounter,app.TimeCounter));
                end
                if app.MapData.flags.so
                    s = squeeze(app.MapData.so(:,:,app.DepthCounter,app.TimeCounter));
                end
                if app.MapData.flags.thetao
                    thetao = squeeze(app.MapData.thetao(:,:,app.DepthCounter,app.TimeCounter));
                end

            elseif app.MapData.flags.depth
                if app.MapData.flags.uo && app.MapData.flags.vo
                    u = squeeze(app.MapData.uo(:,:,app.DepthCounter));
                    v = squeeze(app.MapData.vo(:,:,app.DepthCounter));
                end
                if app.MapData.flags.so
                    s = squeeze(app.MapData.so(:,:,app.DepthCounter));
                end
                if app.MapData.flags.thetao
                    thetao = squeeze(app.MapData.thetao(:,:,app.DepthCounter));
                end

            elseif app.MapData.flags.time
                if app.MapData.flags.uo && app.MapData.flags.vo
                    u = squeeze(app.MapData.uo(:,:,app.TimeCounter));
                    v = squeeze(app.MapData.vo(:,:,app.TimeCounter));
                end
                if app.MapData.flags.so
                    s = squeeze(app.MapData.so(:,:,app.TimeCounter));
                end
                if app.MapData.flags.thetao
                    thetao = squeeze(app.MapData.thetao(:,:,app.TimeCounter));
                end

            else
                if app.MapData.flags.uo && app.MapData.flags.vo
                    u = squeeze(app.MapData.uo(:,:));
                    v = squeeze(app.MapData.vo(:,:));
                end
                if app.MapData.flags.so
                    s = squeeze(app.MapData.so(:,:));
                end
                if app.MapData.flags.thetao
                    thetao = squeeze(app.MapData.thetao(:,:));
                end
            end

            for i = 1:size(lon, 2)
                for j = 1:size(lat, 1)
                    if (tf(count) == 0)
                        if app.MapData.flags.uo && app.MapData.flags.vo
                            u(i, j) = NaN;
                            v(i, j) = NaN;
                        end
                        if app.MapData.flags.so
                            s(i, j) = NaN;
                        end
                        if app.MapData.flags.thetao
                            thetao(i, j) = NaN;
                        end
                    end
                    count = count + 1;
                end
            end

            if app.MapData.flags.uo && app.MapData.flags.vo
                data.u = u;
                data.v = v;
            end
            if app.MapData.flags.so
                data.s = s;
            end
            if app.MapData.flags.thetao
                data.thetao = thetao;
            end
        end

        function tf = inLinearROI(~, position, lon, lat)
            lon = reshape(lon, 1, []);
            lat = reshape(lat, 1, []);
            lon = round(lon, 1);
            lat = round(lat, 1);
            tf = zeros(size(lon));
            if (size(position,1) == 1)
                return;
            end

            for i = 2:size(position,1)
                xv = [position(i-1,1), position(i,1), position(i-1,1)];
                yv = [position(i-1,2), position(i,2), position(i-1,2)];
                [~, tfaux] = inpolygon(lon, lat, round(xv, 1), round(yv, 1));
                tf = tf | tfaux;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AxesTabGroup.Visible = 'off';
            app.MainFigure.Visible = 'off';
            app.FileMenu.Enable = 'off';

            try
                while ~isfolder(app.MapsPath)
                    app.MapsPath = uigetdir("", "Select your maps folder");
                end


                [~, name, ~] = fileparts(app.MapsPath);

                if name == "maps"
                    app.MapDialog = MapSelectorDialog(app, app.MapsPath, 1);
                end

            end
        end

        % Menu selected function: LoadMapMenu
        function LoadMapMenuSelected(app, event)
            app.LoadMapMenu.Enable = 'off';

            app.MapDialog = MapSelectorDialog(app, app.MapsPath, 0);
        end

        % Close request function: MainFigure
        function MainFigureCloseRequest(app, event)
            delete(app.MapDialog);
            try delete(app.Areas.red.dialog), end
            try delete(app.Areas.green.dialog), end
            try delete(app.Areas.blue.dialog), end
            try delete(app.Areas.yellow.dialog), end
            try delete(app.Areas.black.dialog), end
            try delete(app.Trails.red.dialog), end
            try delete(app.Trails.green.dialog), end
            try delete(app.Trails.blue.dialog), end
            try delete(app.Trails.yellow.dialog), end
            try delete(app.Trails.black.dialog), end
            delete(app);
        end

        % Button pushed function: SoonerButton
        function SoonerButtonPushed(app, event)
            if (app.TimeCounter ~= 1)
                app.TimeCounter = app.TimeCounter - 1;
                t = double(app.MapData.time(app.TimeCounter))/24 + datenum('1950-01-01 00:00:00');
                app.TimeText = strcat("(Date: ", datestr(t, 'dd-mm-yyyy HH:MM:SS'), ")");
                updateAxes(app, 0);
                resetAreasAndTrails(app);
            end
        end

        % Button pushed function: LaterButton
        function LaterButtonPushed(app, event)
            if (app.TimeCounter ~= app.MaxTime)
                app.TimeCounter = app.TimeCounter + 1;
                t = double(app.MapData.time(app.TimeCounter))/24 + datenum('1950-01-01 00:00:00');
                app.TimeText = strcat("(Date: ", datestr(t, 'dd-mm-yyyy HH:MM:SS'), ")");
                updateAxes(app, 0);
                resetAreasAndTrails(app);
            end
        end

        % Button pushed function: ShallowerButton
        function ShallowerButtonPushed(app, event)
            if (app.DepthCounter ~= 1)
                app.DepthCounter = app.DepthCounter - 1;
                app.DepthText = strcat("(Depth level: ", string(app.MapData.depth(app.DepthCounter)), ")");
                updateAxes(app, 0);
                resetAreasAndTrails(app);
            end
        end

        % Button pushed function: DeeperButton
        function DeeperButtonPushed(app, event)
            if (app.DepthCounter ~= app.MaxDepth)
                app.DepthCounter = app.DepthCounter + 1;
                app.DepthText = strcat("(Depth level: ", string(app.MapData.depth(app.DepthCounter)), ")");
                updateAxes(app, 0);
                resetAreasAndTrails(app);
            end
        end

        % Selection change function: AxesTabGroup
        function AxesTabGroupSelectionChanged(app, event)
            app.SelectedTab = app.AxesTabGroup.SelectedTab.Title;
        end

        % Value changed function: AreasListBox
        function AreasListBoxValueChanged(app, event)
            if app.AreasListBox.Value == "..."
                app.DeleteAreaButton.Enable = "off";
                app.OpenAreaWindowButton.Enable = "off";
            else
                resetOpenAreaButton(app, 0);
                app.DeleteAreaButton.Enable = "on";
            end
        end

        % Button pushed function: NewAreaButton
        function NewAreaButtonPushed(app, event)
            auxIndex = 0;

            for i = 1:5
                if not(app.Areas.flags(i))
                    auxIndex = i;
                    break;
                end
            end

            if auxIndex == 0
                return;
            end

            switch app.SelectedTab
                case "Current"

                    switch auxIndex
                        case 1
                            app.Areas.red.current = drawpolygon(app.CurrentAxes, 'Color', 'r', 'Deletable', false);
                            app.Areas.red.vertex = app.Areas.red.current.Position;
                            app.Areas.red.listener.current = addlistener(app.Areas.red.current, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Areas.green.current = drawpolygon(app.CurrentAxes, 'Color', 'g', 'Deletable', false);
                            app.Areas.green.vertex = app.Areas.green.current.Position;
                            app.Areas.green.listener.current = addlistener(app.Areas.green.current, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Areas.blue.current = drawpolygon(app.CurrentAxes, 'Color', 'b', 'Deletable', false);
                            app.Areas.blue.vertex = app.Areas.blue.current.Position;
                            app.Areas.blue.listener.current = addlistener(app.Areas.blue.current, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Areas.yellow.current = drawpolygon(app.CurrentAxes, 'Color', 'y', 'Deletable', false);
                            app.Areas.yellow.vertex = app.Areas.yellow.current.Position;
                            app.Areas.yellow.listener.current = addlistener(app.Areas.yellow.current, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Areas.black.current = drawpolygon(app.CurrentAxes, 'Color', 'k', 'Deletable', false);
                            app.Areas.black.vertex = app.Areas.black.current.Position;
                            app.Areas.black.listener.current = addlistener(app.Areas.black.current, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                    end

                case "Salinity"

                    switch auxIndex
                        case 1
                            app.Areas.red.salinity = drawpolygon(app.SalinityAxes, 'Color', 'r', 'Deletable', false);
                            app.Areas.red.vertex = app.Areas.red.salinity.Position;
                            app.Areas.red.listener.salinity = addlistener(app.Areas.red.salinity, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Areas.green.salinity = drawpolygon(app.SalinityAxes, 'Color', 'g', 'Deletable', false);
                            app.Areas.green.vertex = app.Areas.green.salinity.Position;
                            app.Areas.green.listener.salinity = addlistener(app.Areas.green.salinity, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Areas.blue.salinity = drawpolygon(app.SalinityAxes, 'Color', 'b', 'Deletable', false);
                            app.Areas.blue.vertex = app.Areas.blue.salinity.Position;
                            app.Areas.blue.listener.salinity = addlistener(app.Areas.blue.salinity, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Areas.yellow.salinity = drawpolygon(app.SalinityAxes, 'Color', 'y', 'Deletable', false);
                            app.Areas.yellow.vertex = app.Areas.yellow.salinity.Position;
                            app.Areas.yellow.listener.salinity = addlistener(app.Areas.yellow.salinity, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Areas.black.salinity = drawpolygon(app.SalinityAxes, 'Color', 'k', 'Deletable', false);
                            app.Areas.black.vertex = app.Areas.black.salinity.Position;
                            app.Areas.black.listener.salinity = addlistener(app.Areas.black.salinity, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                    end

                case "Temperature"

                    switch auxIndex
                        case 1
                            app.Areas.red.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'r', 'Deletable', false);
                            app.Areas.red.vertex = app.Areas.red.temperature.Position;
                            app.Areas.red.listener.temperature = addlistener(app.Areas.red.temperature, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Areas.green.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'g', 'Deletable', false);
                            app.Areas.green.vertex = app.Areas.green.temperature.Position;
                            app.Areas.green.listener.temperature = addlistener(app.Areas.green.temperature, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Areas.blue.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'b', 'Deletable', false);
                            app.Areas.blue.vertex = app.Areas.blue.temperature.Position;
                            app.Areas.blue.listener.temperature = addlistener(app.Areas.blue.temperature, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Areas.yellow.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'y', 'Deletable', false);
                            app.Areas.yellow.vertex = app.Areas.yellow.temperature.Position;
                            app.Areas.yellow.listener.temperature = addlistener(app.Areas.yellow.temperature, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Areas.black.temperature = drawpolygon(app.TemperatureAxes, 'Color', 'k', 'Deletable', false);
                            app.Areas.black.vertex = app.Areas.black.temperature.Position;
                            app.Areas.black.listener.temperature = addlistener(app.Areas.black.temperature, 'ROIMoved', @(src,evnt)updateAreaDialog(app, auxIndex, app.SelectedTab));
                    end
            end

            app.Areas.flags(auxIndex) = 1;

            updateAreas(app, 1, app.SelectedTab, auxIndex);
        end

        % Button pushed function: OpenAreaWindowButton
        function OpenAreaWindowButtonPushed(app, event)
            selected = app.AreasListBox.Value;

            [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

            switch selected
                case "Red"

                    tf = inROI(app.Areas.red.current, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Areas.red.dialog = DataDialog(app, app.MapData.flags, data, 1, 1);
                    app.Areas.dialogflags(1) = 1;
                case "Green"

                    tf = inROI(app.Areas.green.current, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Areas.green.dialog = DataDialog(app, app.MapData.flags, data, 2, 1);
                    app.Areas.dialogflags(2) = 1;
                case "Blue"

                    tf = inROI(app.Areas.blue.current, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Areas.blue.dialog = DataDialog(app, app.MapData.flags, data, 3, 1);
                    app.Areas.dialogflags(3) = 1;
                case "Yellow"

                    tf = inROI(app.Areas.yellow.current, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Areas.yellow.dialog = DataDialog(app, app.MapData.flags, data, 4, 1);
                    app.Areas.dialogflags(4) = 1;
                case "Black"

                    tf = inROI(app.Areas.black.current, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Areas.black.dialog = DataDialog(app, app.MapData.flags, data, 5, 1);
                    app.Areas.dialogflags(5) = 1;
            end

            app.OpenAreaWindowButton.Enable = 'off';

        end

        % Button pushed function: DeleteAreaButton
        function DeleteAreaButtonPushed(app, event)
            selected = app.AreasListBox.Value;

            switch selected
                case "Red"
                    try delete(app.Areas.red.current), end
                    try delete(app.Areas.red.salinity), end
                    try delete(app.Areas.red.temperature), end
                    try delete(app.Areas.red.dialog), end
                    try delete(app.Areas.red.listener.current), end
                    try delete(app.Areas.red.listener.salinity), end
                    try delete(app.Areas.red.listener.temperature), end
                    app.Areas.flags(1) = 0;
                    app.Areas.dialogflags(1) = 0;
                case "Green"
                    try delete(app.Areas.green.current), end
                    try delete(app.Areas.green.salinity), end
                    try delete(app.Areas.green.temperature), end
                    try delete(app.Areas.green.dialog), end %#ok<*TRYNC>
                    try delete(app.Areas.green.listener.current), end
                    try delete(app.Areas.green.listener.salinity), end
                    try delete(app.Areas.green.listener.temperature), end
                    app.Areas.flags(2) = 0;
                    app.Areas.dialogflags(2) = 0;
                case "Blue"
                    try delete(app.Areas.blue.current), end
                    try delete(app.Areas.blue.salinity), end
                    try delete(app.Areas.blue.temperature), end
                    try delete(app.Areas.blue.dialog), end
                    try delete(app.Areas.blue.listener.current), end
                    try delete(app.Areas.blue.listener.salinity), end
                    try delete(app.Areas.blue.listener.temperature), end
                    app.Areas.flags(3) = 0;
                    app.Areas.dialogflags(3) = 0;
                case "Yellow"
                    try delete(app.Areas.yellow.current), end
                    try delete(app.Areas.yellow.salinity), end
                    try delete(app.Areas.yellow.temperature), end
                    try delete(app.Areas.yellow.dialog), end
                    try delete(app.Areas.yellow.listener.current), end
                    try delete(app.Areas.yellow.listener.salinity), end
                    try delete(app.Areas.yellow.listener.temperature), end
                    app.Areas.flags(4) = 0;
                    app.Areas.dialogflags(4) = 0;
                case "Black"
                    try delete(app.Areas.black.current), end
                    try delete(app.Areas.black.salinity), end
                    try delete(app.Areas.black.temperature), end
                    try delete(app.Areas.black.dialog), end
                    try delete(app.Areas.black.listener.current), end
                    try delete(app.Areas.black.listener.salinity), end
                    try delete(app.Areas.black.listener.temperature), end
                    app.Areas.flags(5) = 0;
                    app.Areas.dialogflags(5) = 0;
            end

            app.AreasListBox.Value = "...";
            app.DeleteAreaButton.Enable = "off";
            app.OpenAreaWindowButton.Enable = 'off';

            updateAreasListBox(app);
        end

        % Value changed function: TrailsListBox
        function TrailsListBoxValueChanged(app, event)
            if app.TrailsListBox.Value == "..."
                app.DeleteTrailButton.Enable = "off";
                app.OpenTrailWindowButton.Enable = "off";
            else
                resetOpenTrailButton(app, 0);
                app.DeleteTrailButton.Enable = "on";
            end
        end

        % Button pushed function: NewTrailButton
        function NewTrailButtonPushed(app, event)
            auxIndex = 0;

            for i = 1:5
                if not(app.Trails.flags(i))
                    auxIndex = i;
                    break;
                end
            end

            if auxIndex == 0
                return;
            end

            switch app.SelectedTab
                case "Current"

                    switch auxIndex
                        case 1
                            app.Trails.red.current = drawpolyline(app.CurrentAxes, 'Color', 'r', 'Deletable', false);
                            app.Trails.red.vertex = app.Trails.red.current.Position;
                            app.Trails.red.listener.current = addlistener(app.Trails.red.current, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Trails.green.current = drawpolyline(app.CurrentAxes, 'Color', 'g', 'Deletable', false);
                            app.Trails.green.vertex = app.Trails.green.current.Position;
                            app.Trails.green.listener.current = addlistener(app.Trails.green.current, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Trails.blue.current = drawpolyline(app.CurrentAxes, 'Color', 'b', 'Deletable', false);
                            app.Trails.blue.vertex = app.Trails.blue.current.Position;
                            app.Trails.blue.listener.current = addlistener(app.Trails.blue.current, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Trails.yellow.current = drawpolyline(app.CurrentAxes, 'Color', 'y', 'Deletable', false);
                            app.Trails.yellow.vertex = app.Trails.yellow.current.Position;
                            app.Trails.yellow.listener.current = addlistener(app.Trails.yellow.current, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Trails.black.current = drawpolyline(app.CurrentAxes, 'Color', 'k', 'Deletable', false);
                            app.Trails.black.vertex = app.Trails.black.current.Position;
                            app.Trails.black.listener.current = addlistener(app.Trails.black.current, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                    end

                case "Salinity"

                    switch auxIndex
                        case 1
                            app.Trails.red.salinity = drawpolyline(app.SalinityAxes, 'Color', 'r', 'Deletable', false);
                            app.Trails.red.vertex = app.Trails.red.salinity.Position;
                            app.Trails.red.listener.salinity = addlistener(app.Trails.red.salinity, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Trails.green.salinity = drawpolyline(app.SalinityAxes, 'Color', 'g', 'Deletable', false);
                            app.Trails.green.vertex = app.Trails.green.salinity.Position;
                            app.Trails.green.listener.salinity = addlistener(app.Trails.green.salinity, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Trails.blue.salinity = drawpolyline(app.SalinityAxes, 'Color', 'b', 'Deletable', false);
                            app.Trails.blue.vertex = app.Trails.blue.salinity.Position;
                            app.Trails.blue.listener.salinity = addlistener(app.Trails.blue.salinity, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Trails.yellow.salinity = drawpolyline(app.SalinityAxes, 'Color', 'y', 'Deletable', false);
                            app.Trails.yellow.vertex = app.Trails.yellow.salinity.Position;
                            app.Trails.yellow.listener.salinity = addlistener(app.Trails.yellow.salinity, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Trails.black.salinity = drawpolyline(app.SalinityAxes, 'Color', 'k', 'Deletable', false);
                            app.Trails.black.vertex = app.Trails.black.salinity.Position;
                            app.Trails.black.listener.salinity = addlistener(app.Trails.black.salinity, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                    end

                case "Temperature"

                    switch auxIndex
                        case 1
                            app.Trails.red.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'r', 'Deletable', false);
                            app.Trails.red.vertex = app.Trails.red.temperature.Position;
                            app.Trails.red.listener.temperature = addlistener(app.Trails.red.temperature, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 2
                            app.Trails.green.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'g', 'Deletable', false);
                            app.Trails.green.vertex = app.Trails.green.temperature.Position;
                            app.Trails.green.listener.temperature = addlistener(app.Trails.green.temperature, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 3
                            app.Trails.blue.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'b', 'Deletable', false);
                            app.Trails.blue.vertex = app.Trails.blue.temperature.Position;
                            app.Trails.blue.listener.temperature = addlistener(app.Trails.blue.temperature, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 4
                            app.Trails.yellow.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'y', 'Deletable', false);
                            app.Trails.yellow.vertex = app.Trails.yellow.temperature.Position;
                            app.Trails.yellow.listener.temperature = addlistener(app.Trails.yellow.temperature, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                        case 5
                            app.Trails.black.temperature = drawpolyline(app.TemperatureAxes, 'Color', 'k', 'Deletable', false);
                            app.Trails.black.vertex = app.Trails.black.temperature.Position;
                            app.Trails.black.listener.temperature = addlistener(app.Trails.black.temperature, 'ROIMoved', @(src,evnt)updateTrailDialog(app, auxIndex, app.SelectedTab));
                    end
            end

            app.Trails.flags(auxIndex) = 1;

            updateTrails(app, 1, app.SelectedTab, auxIndex);
        end

        % Button pushed function: OpenTrailWindowButton
        function OpenTrailWindowButtonPushed(app, event)
            selected = app.TrailsListBox.Value;

            [lon, lat] = meshgrid(app.MapData.longitude, app.MapData.latitude);

            switch selected
                case "Red"

                    tf = inLinearROI(app, app.Trails.red.current.Position, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Trails.red.dialog = DataDialog(app, app.MapData.flags, data, 1, 0);
                    app.Trails.dialogflags(1) = 1;
                case "Green"

                    tf = inLinearROI(app, app.Trails.green.current.Position, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Trails.green.dialog = DataDialog(app, app.MapData.flags, data, 2, 0);
                    app.Trails.dialogflags(2) = 1;
                case "Blue"

                    tf = inLinearROI(app, app.Trails.blue.current.Position, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Trails.blue.dialog = DataDialog(app, app.MapData.flags, data, 3, 0);
                    app.Trails.dialogflags(3) = 1;
                case "Yellow"

                    tf = inLinearROI(app, app.Trails.yellow.current.Position, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Trails.yellow.dialog = DataDialog(app, app.MapData.flags, data, 4, 0);
                    app.Trails.dialogflags(4) = 1;
                case "Black"

                    tf = inLinearROI(app, app.Trails.black.current.Position, lon, lat);

                    data = getDataFromTf(app, lon, lat, tf);

                    app.Trails.black.dialog = DataDialog(app, app.MapData.flags, data, 5, 0);
                    app.Trails.dialogflags(5) = 1;
            end

            app.OpenTrailWindowButton.Enable = 'off';
        end

        % Button pushed function: DeleteTrailButton
        function DeleteTrailButtonPushed(app, event)
            selected = app.TrailsListBox.Value;

            switch selected
                case "Red"
                    try delete(app.Trails.red.current), end
                    try delete(app.Trails.red.salinity), end
                    try delete(app.Trails.red.temperature), end
                    try delete(app.Trails.red.dialog), end
                    try delete(app.Trails.red.listener.current), end
                    try delete(app.Trails.red.listener.salinity), end
                    try delete(app.Trails.red.listener.temperature), end
                    app.Trails.flags(1) = 0;
                    app.Trails.dialogflags(1) = 0;
                case "Green"
                    try delete(app.Trails.green.current), end
                    try delete(app.Trails.green.salinity), end
                    try delete(app.Trails.green.temperature), end
                    try delete(app.Trails.green.dialog), end %#ok<*TRYNC>
                    try delete(app.Trails.green.listener.current), end
                    try delete(app.Trails.green.listener.salinity), end
                    try delete(app.Trails.green.listener.temperature), end
                    app.Trails.flags(2) = 0;
                    app.Trails.dialogflags(2) = 0;
                case "Blue"
                    try delete(app.Trails.blue.current), end
                    try delete(app.Trails.blue.salinity), end
                    try delete(app.Trails.blue.temperature), end
                    try delete(app.Trails.blue.dialog), end
                    try delete(app.Trails.blue.listener.current), end
                    try delete(app.Trails.blue.listener.salinity), end
                    try delete(app.Trails.blue.listener.temperature), end
                    app.Trails.flags(3) = 0;
                    app.Trails.dialogflags(3) = 0;
                case "Yellow"
                    try delete(app.Trails.yellow.current), end
                    try delete(app.Trails.yellow.salinity), end
                    try delete(app.Trails.yellow.temperature), end
                    try delete(app.Trails.yellow.dialog), end
                    try delete(app.Trails.yellow.listener.current), end
                    try delete(app.Trails.yellow.listener.salinity), end
                    try delete(app.Trails.yellow.listener.temperature), end
                    app.Trails.flags(4) = 0;
                    app.Trails.dialogflags(4) = 0;
                case "Black"
                    try delete(app.Trails.black.current), end
                    try delete(app.Trails.black.salinity), end
                    try delete(app.Trails.black.temperature), end
                    try delete(app.Trails.black.dialog), end
                    try delete(app.Trails.black.listener.current), end
                    try delete(app.Trails.black.listener.salinity), end
                    try delete(app.Trails.black.listener.temperature), end
                    app.Trails.flags(5) = 0;
                    app.Trails.dialogflags(5) = 0;
            end

            app.TrailsListBox.Value = "...";
            app.DeleteTrailButton.Enable = "off";
            app.OpenTrailWindowButton.Enable = 'off';

            updateTrailsListBox(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'OceanViewer';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @MainFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MainFigure);
            app.FileMenu.Text = 'File';
            app.LoadMapMenu = uimenu(app.FileMenu);
            app.LoadMapMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMapMenuSelected, true);
            app.LoadMapMenu.Text = 'Load Map';
            app.AxesTabGroup = uitabgroup(app.MainFigure);
            app.AxesTabGroup.SelectionChangedFcn = createCallbackFcn(app, @AxesTabGroupSelectionChanged, true);
            app.CurrentTab = uitab(app.AxesTabGroup);
            app.CurrentTab.Title = 'Current';
            app.CurrentAxes = uiaxes(app.CurrentTab);
            title(app.CurrentAxes, 'Current Data')
            xlabel(app.CurrentAxes, 'X')
            ylabel(app.CurrentAxes, 'Y')
            zlabel(app.CurrentAxes, 'Z')
            app.TemperatureTab = uitab(app.AxesTabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.TemperatureAxes = uiaxes(app.TemperatureTab);
            title(app.TemperatureAxes, 'Temperature Data')
            xlabel(app.TemperatureAxes, 'X')
            ylabel(app.TemperatureAxes, 'Y')
            zlabel(app.TemperatureAxes, 'Z')
            app.SalinityTab = uitab(app.AxesTabGroup);
            app.SalinityTab.Title = 'Salinity';
            app.SalinityAxes = uiaxes(app.SalinityTab);
            title(app.SalinityAxes, 'Salinity Data')
            xlabel(app.SalinityAxes, 'X')
            ylabel(app.SalinityAxes, 'Y')
            zlabel(app.SalinityAxes, 'Z')
            app.Panel = uipanel(app.MainFigure);
            app.TimeControllerPanel = uipanel(app.Panel);
            app.TimeControllerPanel.Enable = 'off';
            app.TimeControllerPanel.Title = 'Time Controller';
            app.TimeControllerPanel.Visible = 'off';
            app.SoonerButton = uibutton(app.TimeControllerPanel, 'push');
            app.SoonerButton.ButtonPushedFcn = createCallbackFcn(app, @SoonerButtonPushed, true);
            app.SoonerButton.Text = 'Sooner';
            app.LaterButton = uibutton(app.TimeControllerPanel, 'push');
            app.LaterButton.ButtonPushedFcn = createCallbackFcn(app, @LaterButtonPushed, true);
            app.LaterButton.Text = 'Later';
            app.DepthControllerPanel = uipanel(app.Panel);
            app.DepthControllerPanel.Enable = 'off';
            app.DepthControllerPanel.Title = 'Depth Controller';
            app.DepthControllerPanel.Visible = 'off';
            app.DeeperButton = uibutton(app.DepthControllerPanel, 'push');
            app.DeeperButton.ButtonPushedFcn = createCallbackFcn(app, @DeeperButtonPushed, true);
            app.DeeperButton.Text = 'Deeper';
            app.ShallowerButton = uibutton(app.DepthControllerPanel, 'push');
            app.ShallowerButton.ButtonPushedFcn = createCallbackFcn(app, @ShallowerButtonPushed, true);
            app.ShallowerButton.Text = 'Shallower';
            app.AreasAndTrailsTabGroup = uitabgroup(app.Panel);
            app.AreasAndTrailsTabGroup.Visible = 'off';
            app.AreasTab = uitab(app.AreasAndTrailsTabGroup);
            app.AreasTab.Title = 'Areas';
            app.AreasListBox = uilistbox(app.AreasTab);
            app.AreasListBox.Items = {'...'};
            app.AreasListBox.ValueChangedFcn = createCallbackFcn(app, @AreasListBoxValueChanged, true);
            app.AreasListBox.Value = '...';
            app.NewAreaButton = uibutton(app.AreasTab, 'push');
            app.NewAreaButton.ButtonPushedFcn = createCallbackFcn(app, @NewAreaButtonPushed, true);
            app.NewAreaButton.Text = 'New Area';
            app.DeleteAreaButton = uibutton(app.AreasTab, 'push');
            app.DeleteAreaButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAreaButtonPushed, true);
            app.DeleteAreaButton.Enable = 'off';
            app.DeleteAreaButton.Text = 'Delete Area';
            app.OpenAreaWindowButton = uibutton(app.AreasTab, 'push');
            app.OpenAreaWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAreaWindowButtonPushed, true);
            app.OpenAreaWindowButton.Enable = 'off';
            app.OpenAreaWindowButton.Text = 'Open Area Window';
            app.TrailsTab = uitab(app.AreasAndTrailsTabGroup);
            app.TrailsTab.Title = 'Trails';
            app.TrailsListBox = uilistbox(app.TrailsTab);
            app.TrailsListBox.Items = {'...'};
            app.TrailsListBox.ValueChangedFcn = createCallbackFcn(app, @TrailsListBoxValueChanged, true);
            app.TrailsListBox.Value = '...';
            app.NewTrailButton = uibutton(app.TrailsTab, 'push');
            app.NewTrailButton.ButtonPushedFcn = createCallbackFcn(app, @NewTrailButtonPushed, true);
            app.NewTrailButton.Text = 'New Trail';
            app.DeleteTrailButton = uibutton(app.TrailsTab, 'push');
            app.DeleteTrailButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTrailButtonPushed, true);
            app.DeleteTrailButton.Enable = 'off';
            app.DeleteTrailButton.Text = 'Delete Trail';
            app.OpenTrailWindowButton = uibutton(app.TrailsTab, 'push');
            app.OpenTrailWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTrailWindowButtonPushed, true);
            app.OpenTrailWindowButton.Enable = 'off';
            app.OpenTrailWindowButton.Text = 'Open Trail Window';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end